classdef Testing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OpenButton                  matlab.ui.control.Button
        CreateExcel                 matlab.ui.control.CheckBox
        Execute                     matlab.ui.control.Button
        SelectmodeltotestLabel      matlab.ui.control.Label
        UpdateHarness               matlab.ui.control.CheckBox
        UpdateCases                 matlab.ui.control.CheckBox
        AUTOSARButton               matlab.ui.control.StateButton
        TestingPanel                matlab.ui.container.Panel
        RunTests                    matlab.ui.control.CheckBox
        MILReport                   matlab.ui.control.CheckBox
        Panel                       matlab.ui.container.Panel
        RunSIL                      matlab.ui.control.CheckBox
        SLDVButton                  matlab.ui.control.StateButton
        SILReport                   matlab.ui.control.CheckBox
        ModelNameEditFieldLabel     matlab.ui.control.Label
        ModelNameEditField          matlab.ui.control.EditField
        ModelVersionEditFieldLabel  matlab.ui.control.Label
        ModelVersionEditField       matlab.ui.control.EditField
        ThisisanLabel               matlab.ui.control.Label
        modelLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        rootPath
        ModelName
        OnlyModelName
        harness_name
        Create_excel
        dataMatfile
        port_data
        no_inports
        no_rnbls
        no_outports
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','clear');
        end

        % Callback function
        function OpenButtonPushed(app, event)
            [app.ModelName,app.rootPath] = uigetfile();
            if isequal(app.ModelName,0)
            else
                app.OnlyModelName = strsplit(app.ModelName,'.');
                app.OnlyModelName = app.OnlyModelName{1,1};
                app.harness_name = sprintf('%s_TestHarness',app.OnlyModelName);
                app.Execute.Enable = 1;
                app.SelectmodeltotestLabel.Text = 'Uncheck "Create harness & Test case excel" to execute MIL';
                app.ModelNameEditField.Value = app.ModelName;
            end
        end

        % Callback function
        function CreateExcelValueChanged(app, event)
            if isequal(app.CreateExcel.Value, 1)
                app.Execute.Text = 'Create harness';
                app.SelectmodeltotestLabel.Text = 'Uncheck "Create harness & Test case excel" to execute MIL';
            else
                app.Execute.Text = 'Execute MIL test';
                app.SelectmodeltotestLabel.Text = 'Click "Execute MIL test" button';
            end
            
        end

        % Callback function
        function ExecuteButtonPushed(app, event)
            load_system(app.ModelName);
            DataDictionary = get_param(app.OnlyModelName,'DataDictionary');
            DataDictObj = Simulink.data.dictionary.open(DataDictionary);
            DataDictSec = getSection(DataDictObj,'Design Data');
            
            if isequal(app.CreateExcel.Value, 1)
                save(sprintf('%s_data.mat',app.harness_name));
                app.dataMatfile = matfile(sprintf('%s_data.mat',app.harness_name),'Writable',true);
                
                harness_handle = new_system(app.harness_name);
                set_param(harness_handle,'Solver','FixedStepDiscrete','FixedStep','0.01');
                open_system(harness_handle);
                ref_handle = add_block('simulink/Ports & Subsystems/Model',...
                    sprintf('%s/%s',app.harness_name,app.OnlyModelName),'ModelFile',app.ModelName,...
                    'ShowModelInitializePort','on','ShowModelPeriodicEventPorts','on');
                set_param(harness_handle, 'ZoomFactor','100'); % fit to screen
                max_ports = max(get_param(ref_handle, 'Ports'));
                ref_position = get_param(ref_handle,'Position');
                ref_position(4) = ref_position(2) + 45*max_ports;
                ref_position(3) = ref_position(1) + 520;
                set_param(ref_handle,'Position',ref_position);
                set_param(harness_handle, 'ZoomFactor','FitSystem'); % fit to screen
                set_param(harness_handle,'DataDictionary',DataDictionary);
                %add_block('simulink/Signal Attributes/Data Type Conversion','OutDataTypeStr','AliasType or Enum: <classname>');
                inports = find_system(app.OnlyModelName, 'SearchDepth', 1, 'FollowLinks', 'on', 'BlockType', 'Inport');
                AllPortHandles = get_param(ref_handle,'PortHandles');
                inportHandles = AllPortHandles.Inport;
                all_PortConnectivity = get_param(ref_handle,'PortConnectivity');
                all_PortPos = {all_PortConnectivity.Position};
                app.port_data = struct('Name',{},'OutDataType',{},'BaseDataType',{},'Position',{},'Handle',{},'Values',{});
                app.no_rnbls = 0;
                app.no_inports = length(inports);
                for AllPorts = 1:app.no_inports
                    name = get_param(inports(AllPorts),'Name');
                    app.port_data(AllPorts).Name = name{1,1};
                    app.port_data(AllPorts).Position = all_PortPos(AllPorts);
                    app.port_data(AllPorts).Position = app.port_data(AllPorts).Position{1,1};
                    app.port_data(AllPorts).Handle = inportHandles(AllPorts);
                    if cellfun(@isempty,regexpi(inports(AllPorts),'Rnbl_.*'))
                        out_data_type = get_param(inports(AllPorts),'OutDataTypeStr');
                        app.port_data(AllPorts).OutDataType = out_data_type{1,1};
                        if isempty(regexpi(app.port_data(AllPorts).OutDataType,'Enum: .*'))
                            entryObj = getEntry(DataDictSec,app.port_data(AllPorts).OutDataType);
                            aliasValue = getValue(entryObj);
                            app.port_data(AllPorts).BaseDataType = aliasValue.BaseType;
                        else
                            app.port_data(AllPorts).BaseDataType = 'Enum';
                        end
                    else
                        app.no_rnbls = app.no_rnbls + 1;
                    end
                end
                AllPorts = AllPorts + 1;
                app.port_data(AllPorts).Name = 'initialize';
                app.port_data(AllPorts).Position = all_PortPos(AllPorts);
                app.port_data(AllPorts).Position = app.port_data(AllPorts).Position{1,1};
                app.port_data(AllPorts).Handle = inportHandles(AllPorts);
                
                
                outports = find_system(app.OnlyModelName, 'SearchDepth', 1, 'FollowLinks', 'on', 'BlockType', 'Outport');
                outportHandles = AllPortHandles.Outport;
                app.no_outports = length(outports);
                for AllPorts = (app.no_inports+2):(app.no_inports+1+app.no_outports)
                    name = get_param(outports(AllPorts-app.no_inports-1),'Name');
                    app.port_data(AllPorts).Name = name{1,1};
                    out_data_type = get_param(outports(AllPorts-app.no_inports-1),'OutDataTypeStr');
                    app.port_data(AllPorts).OutDataType = out_data_type{1,1};
                    app.port_data(AllPorts).Position = all_PortPos(AllPorts);
                    app.port_data(AllPorts).Position = app.port_data(AllPorts).Position{1,1};
                    app.port_data(AllPorts).Handle = outportHandles(AllPorts-app.no_inports-1);
                    if isempty(regexpi(app.port_data(AllPorts).OutDataType,'Enum: .*'))
                        entryObj = getEntry(DataDictSec,app.port_data(AllPorts).OutDataType);
                        aliasValue = getValue(entryObj);
                        app.port_data(AllPorts).BaseDataType = aliasValue.BaseType;
                    else
                        app.port_data(AllPorts).BaseDataType = 'Enum';
                    end
                end
                
                add_block('simulink/Ports & Subsystems/Function-Call Generator',...
                        sprintf('%s/InitFunCallGen',app.harness_name),'sample_time','11',...
                        'Position',[app.port_data(app.no_inports+1).Position(1)-85-20 app.port_data(app.no_inports+1).Position(2)-11 app.port_data(app.no_inports+1).Position(1)-85 app.port_data(app.no_inports+1).Position(2)+11]);
                add_line(app.harness_name,'InitFunCallGen/1',sprintf('%s/%d',app.OnlyModelName,app.no_inports+1));
                    
                if app.no_rnbls == 1
                    add_block('simulink/Ports & Subsystems/Function-Call Generator',...
                        sprintf('%s/FunCallGen',app.harness_name),'sample_time','0.01',...
                        'Position',[app.port_data(1).Position(1)-85-20 app.port_data(1).Position(2)-11 app.port_data(1).Position(1)-85 app.port_data(1).Position(2)+11]);
                    add_line(app.harness_name,'FunCallGen/1',sprintf('%s/1',app.OnlyModelName));  
                else
                    add_block('simulink/Ports & Subsystems/Function-Call Split',...
                        sprintf('%s/FunCallSplit',app.harness_name),'NumOutputPorts',num2str(app.no_rnbls),...
                        'Position',[app.port_data(1).Position(1)-55 app.port_data(1).Position(2)-25 app.port_data(1).Position(1)-30 app.port_data(1).Position(2)+25+((app.no_rnbls-1)*45)]);
                    
                    outSrc = cell.empty(0,app.no_rnbls);
                    inDest = outSrc;
                    for i = 1:app.no_rnbls
                        outSrc(i) = {sprintf('FunCallSplit/%d',i)};
                        inDest(i) = {sprintf('%s/%d',app.OnlyModelName,i)};
                    end
                    add_line(app.harness_name,outSrc,inDest,'autorouting','on');
                    
                    add_block('simulink/Ports & Subsystems/Function-Call Generator',...
                        sprintf('%s/FunCallGen',app.harness_name),'sample_time','0.01',...
                        'Position',[app.port_data(1).Position(1)-85-20 app.port_data(1).Position(2)-11+((app.no_rnbls-1)*45/2) app.port_data(1).Position(1)-85 app.port_data(1).Position(2)+11+((app.no_rnbls-1)*45/2)]);
                    add_line(app.harness_name,{'FunCallGen/1'},{'FunCallSplit/1'},'autorouting','on');
                end
                
                app.no_inports = app.no_inports - app.no_rnbls;
                Excel = actxserver('Excel.Application');
                Ex_Workbook = Excel.Workbooks.Add;
                Ex_Sheets = Excel.ActiveWorkbook.Sheets;
                Ex_actSheet = Ex_Sheets.get('Item',1);
                Ex_actSheet.Name = app.OnlyModelName;
                SaveAs(Ex_Workbook,sprintf('%s%s_MIL_Test.xlsx',app.rootPath,app.OnlyModelName));
                Excel.Visible = 1;
                Ex_actSheet.Cells.HorizontalAlignment = -4108;
                Ex_actSheet.Cells.VerticalAlignment = -4108;
                
                Ex_range = get(Ex_actSheet,'Range','A1');
                Ex_range.Value = 'Requirement ID';
                Ex_range.ColumnWidth = 44;
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',0,1);
                Ex_range.Value = 'Test Case ID';
                Ex_range.ColumnWidth = 33;
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',0,1);
                Ex_range.Value = 'Test Description';
                Ex_range.ColumnWidth = 46;
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',0,1);
                Ex_range.Value = 'Time';
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',1,0);
                Ex_range.Value = '(in Sec)';
                Ex_range.ColumnWidth = 11;
                Ex_range = Ex_range.get('Offset',-1,1);
                %Ex_range.Interior.ColorIndex = 20->liteTorquoise, 43-> Lime,6-> yellow, 44-> Gold   
                %Ex_range.EntireRow.Interior.ColorIndex = 20
                %Ex_range.EntireColumn.AutoFit
                
                for port_no = app.no_rnbls+1: app.no_rnbls+app.no_inports+app.no_outports+2
                    if (port_no > app.no_rnbls) && port_no < (app.no_rnbls + app.no_inports + 1)
                        %inports
                        Ex_range.Value = 'Source: Input';
                        Ex_range.Interior.ColorIndex = 43;
                        Ex_range = Ex_range.get('Offset',1,0);
                        Ex_range.Value = app.port_data(port_no).Name;
                        Ex_range.Interior.ColorIndex = 43;
                        Ex_range = Ex_range.get('Offset',1,0);
                        if isequal(app.port_data(port_no).BaseDataType,'Enum')
                            Ex_range.Value = app.port_data(port_no).OutDataType;
                        else
                            Ex_range.Value = sprintf('Type: %s',app.port_data(port_no).BaseDataType);
                        end
                        Ex_range.Interior.ColorIndex = 43;
                        Ex_range.EntireColumn.AutoFit;
                        Ex_range = Ex_range.get('Offset',-2,1);
                    elseif port_no > (app.no_rnbls + app.no_inports + 1) && port_no < (app.no_rnbls+app.no_inports+app.no_outports+2)
                        %outports
                        Ex_range.Value = 'Expected Output';
                        Ex_range.Interior.ColorIndex = 6;
                        Ex_range = Ex_range.get('Offset',1,0);
                        Ex_range.Value = app.port_data(port_no).Name;
                        Ex_range.Interior.ColorIndex = 6;
                        Ex_range = Ex_range.get('Offset',1,0);
                        if isequal(app.port_data(port_no).BaseDataType,'Enum')
                            Ex_range.Value = app.port_data(port_no).OutDataType;
                        else
                            Ex_range.Value = sprintf('Type: %s',app.port_data(port_no).BaseDataType);
                        end
                        Ex_range.Interior.ColorIndex = 6;
                        Ex_range.EntireColumn.AutoFit;
                        Ex_range = Ex_range.get('Offset',-2,app.no_outports);
                        
                        Ex_range.Value = 'Source: Output';
                        Ex_range.Interior.ColorIndex = 44;
                        Ex_range = Ex_range.get('Offset',1,0);
                        Ex_range.Value = app.port_data(port_no).Name;
                        Ex_range.Interior.ColorIndex = 44;
                        Ex_range = Ex_range.get('Offset',1,0);
                        if isequal(app.port_data(port_no).BaseDataType,'Enum')
                            Ex_range.Value = app.port_data(port_no).OutDataType;
                        else
                            Ex_range.Value = sprintf('Type: %s',app.port_data(port_no).BaseDataType);
                        end
                        Ex_range.Interior.ColorIndex = 44;
                        Ex_range.EntireColumn.AutoFit;
                        Ex_range = Ex_range.get('Offset',-2,-(app.no_outports-1));
                    end
                end
                
                Ex_range = Ex_range.get('Offset',0,app.no_outports);
                Ex_range.Value = 'Result';
                Ex_range.ColumnWidth = 19;
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',0,1);
                Ex_range.Value = 'remark';
                Ex_range.ColumnWidth = 40;
                Ex_range.Interior.ColorIndex = 20;
                Ex_range = Ex_range.get('Offset',3,-(5+app.no_inports+(2*app.no_outports)));
                Ex_range.Value = '*Start Here*';
                Ex_actSheet.Cells.Borders.Item('xlInsideHorizontal').LineStyle = 1;
                Ex_actSheet.Cells.Borders.Item('xlInsideVertical').LineStyle = 1;
                Ex_Workbook.Save;
                
                app.dataMatfile.port_data = app.port_data;
                app.dataMatfile.no_inports = app.no_inports;
                app.dataMatfile.no_outports = app.no_outports;
                app.dataMatfile.no_rnbls = app.no_rnbls;
                
                save_system(harness_handle);
            else
                app.dataMatfile = matfile(sprintf('%s_data.mat',app.harness_name),'Writable',true);
                app.port_data = app.dataMatfile.port_data;
                app.no_inports = app.dataMatfile.no_inports;
                app.no_outports = app.dataMatfile.no_outports;
                app.no_rnbls = app.dataMatfile.no_rnbls;
                
                Excel = actxserver('Excel.Application');
                Ex_Workbook = Excel.Workbooks.Open(sprintf('%s%s_MIL_Test.xlsx',app.rootPath,app.OnlyModelName));
                Ex_Sheets = Excel.ActiveWorkbook.Sheets;
                Ex_actSheet = Ex_Sheets.get('Item',1);
                Excel.Visible = 1;
                
                load_system(app.ModelName);
                load_system(sprintf('%s.slx',app.harness_name));
                harness_handle = get_param(app.harness_name,'Handle');
                open_system(harness_handle);
                
                DataDictionary = get_param(app.OnlyModelName,'DataDictionary');
                DataDictObj = Simulink.data.dictionary.open(DataDictionary);
                %DataDictSec = getSection(DataDictObj,'Design Data');
                
                Ex_range = get(Ex_actSheet,'Range','D4');
                all_time_values = 0;
                simulation_time = 0;
                no_rows = 0;
                while ~isnan(Ex_range.Value)
                    no_rows = no_rows + 1;
                    all_time_values(no_rows) = Ex_range.Value;
                    simulation_time = simulation_time + Ex_range.Value;
                    Ex_range = Ex_range.get('Offset',1,0);
                end
                
                set_param(sprintf('%s/InitFunCallGen',app.harness_name),'sample_time',num2str(simulation_time+1));
                
                Ex_range = get(Ex_actSheet,'Range','E4');
                
                for port_no = app.no_rnbls+1: app.no_rnbls+app.no_inports+app.no_outports+2
                    if (port_no > app.no_rnbls) && port_no < (app.no_rnbls + app.no_inports + 1)
                        %inports
                        app.port_data(port_no).Values = zeros(1,int32(simulation_time*100));
                    elseif port_no > (app.no_rnbls + app.no_inports + 1) && port_no < (app.no_rnbls+app.no_inports+app.no_outports+2)
                        %outports
                        app.port_data(port_no).Values = zeros(1,int32(simulation_time*100));
                    end
                end
                
                time_milestone = all_time_values(1);
                time_index = 1;
                value_index = 1;
                for time = 0:0.005:simulation_time
                    if time < time_milestone
                        for port_no = app.no_rnbls+1: app.no_rnbls+app.no_inports+app.no_outports+2
                            if (port_no > app.no_rnbls) && port_no < (app.no_rnbls + app.no_inports + 1)
                                %inports
                                if isequal(app.port_data(port_no).BaseDataType,'Enum')
                                    app.port_data(port_no).Values(value_index) = ...
                                        eval(sprintf('%s.%s',app.port_data(port_no).OutDataType(7:end),Ex_range.Value));
                                else
                                    app.port_data(port_no).Values(value_index) = Ex_range.Value;
                                end
                                Ex_range = Ex_range.get('Offset',0,1);
                            elseif port_no > (app.no_rnbls + app.no_inports + 1) && port_no < (app.no_rnbls+app.no_inports+app.no_outports+2)
                                %outports
                                if isequal( app.port_data(port_no).BaseDataType,'Enum')
                                    app.port_data(port_no).Values(value_index) = ...
                                        eval(sprintf('%s.%s',app.port_data(port_no).OutDataType(7:end),Ex_range.Value));
                                else
                                    app.port_data(port_no).Values(value_index) = Ex_range.Value;
                                end
                                Ex_range = Ex_range.get('Offset',0,1);
                            end
                        end
                        Ex_range = Ex_range.get('Offset',0,-(app.no_inports+app.no_outports));
                    else
                        time_index = time_index + 1;
                        time_milestone = time_milestone + all_time_values(time_index);
                        Ex_range = Ex_range.get('Offset',1,0);
                        for port_no = app.no_rnbls+1: app.no_rnbls+app.no_inports+app.no_outports+2
                            if (port_no > app.no_rnbls) && port_no < (app.no_rnbls + app.no_inports + 1)
                                %inports
                                if isequal(app.port_data(port_no).BaseDataType,'Enum')
                                    app.port_data(port_no).Values(value_index) = ...
                                        eval(sprintf('%s.%s',app.port_data(port_no).OutDataType(7:end),Ex_range.Value));
                                else
                                    app.port_data(port_no).Values(value_index) = Ex_range.Value;
                                end
                                Ex_range = Ex_range.get('Offset',0,1);
                            elseif port_no > (app.no_rnbls + app.no_inports + 1) && port_no < (app.no_rnbls+app.no_inports+app.no_outports+2)
                                %outports
                                if isequal( app.port_data(port_no).BaseDataType,'Enum')
                                    app.port_data(port_no).Values(value_index) = ...
                                        eval(sprintf('%s.%s',app.port_data(port_no).OutDataType(7:end),Ex_range.Value));
                                else
                                    app.port_data(port_no).Values(value_index) = Ex_range.Value;
                                end
                                Ex_range = Ex_range.get('Offset',0,1);
                            end
                        end
                        Ex_range = Ex_range.get('Offset',0,-(app.no_inports+app.no_outports));
                    end
                    value_index = value_index + 1;
                end
                
                timeStamps = 0:0.005:simulation_time;
                
                %Inputs_ExpectedOutput = Simulink.SimulationData.Dataset;
                %Inputs_ExpectedOutput.Name = 'Input&ExpectedOutput';
                
                scope_height = app.no_outports*45+40;
                ref_position = get_param(sprintf('%s/%s',app.harness_name,app.OnlyModelName),'Position');
                scope_handle = add_block('simulink/Sinks/Scope',sprintf('%s/Scope',app.harness_name),...
                	'Position',[ref_position(3)+420 ref_position(4)-scope_height ref_position(3)+550 ref_position(4)+scope_height]);
                scope_config = get_param(scope_handle,'ScopeConfiguration');
                scope_config.NumInputPorts = num2str(2*app.no_outports);
                scope_ports = get_param(scope_handle,'PortConnectivity');

                port_no = app.no_rnbls+1;
                %values = size(timeStamps);
                signal_no = 1;
                %re_timeStamps = reshape(timeStamps,values(2),1);
                %data = reshape(app.port_data(port_no).Values,values(2),1);
                %temp_timeseries = timeseries(data,re_timeStamps,'Name',app.port_data(port_no).Name);
                block = signalbuilder(sprintf('%s/SignalGen',app.harness_name),'create',timeStamps,app.port_data(port_no).Values,app.port_data(port_no).Name,'Imported_group');
                set_param(block,'Position',[app.port_data(port_no).Position(1)-735 app.port_data(port_no).Position(2)-31 app.port_data(port_no).Position(1)-390 ...
                    app.port_data(port_no).Position(2)+((app.no_inports+app.no_outports-1)*45)+11]);
                gen_handles = get_param(block,'PortHandles');
                ref_port_handles = get_param(sprintf('%s/%s',app.harness_name,app.OnlyModelName),'PortHandles');
                convert_handle = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d',app.harness_name,signal_no),...
                    'Position',[app.port_data(port_no).Position(1)-265 app.port_data(port_no).Position(2)-10 app.port_data(port_no).Position(1)-190 app.port_data(port_no).Position(2)+10]...
                    ,'OutDataTypeStr',app.port_data(port_no).OutDataType);
                convert_port = get_param(convert_handle,'PortHandles');
                set_param(convert_port.Outport(1),'DataLogging','on','DataLoggingName',app.port_data(port_no).Name);
                if isequal(app.port_data(port_no).BaseDataType,'Enum')
                	old_pos = get_param(convert_handle,'Position');
                    int_convert = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d_int8',app.harness_name,signal_no),...
                    	'Position', [old_pos(1)-100 old_pos(2) old_pos(3)-100 old_pos(4)],'OutDataTypeStr','uint8');
                    int_convert_port = get_param(int_convert,'PortHandles');
                    add_line(app.harness_name,gen_handles.Outport(signal_no),int_convert_port.Inport(1));
                    add_line(app.harness_name,int_convert_port.Outport(1),convert_port.Inport(1));
                else
                   	add_line(app.harness_name,gen_handles.Outport(signal_no),convert_port.Inport(1));
                end
                	line_handle = add_line(app.harness_name,convert_port.Outport(1),ref_port_handles.Inport(port_no));
                	set_param(line_handle,'Name',app.port_data(port_no).Name);
                for port_no = app.no_rnbls+2: app.no_rnbls+app.no_inports+app.no_outports+2
                    if (port_no > app.no_rnbls) && port_no < (app.no_rnbls + app.no_inports + 1)
                        %inports
                        signal_no = signal_no + 1;
                        %data = reshape(app.port_data(port_no).Values,values(2),1);
                        %temp_timeseries = timeseries(data,re_timeStamps,'Name',app.port_data(port_no).Name);
                        signalbuilder(block,'appendsignal',timeStamps,app.port_data(port_no).Values,app.port_data(port_no).Name);
                        gen_handles = get_param(block,'PortHandles');
                        convert_handle = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d',app.harness_name,signal_no),...
                            'Position',[app.port_data(port_no).Position(1)-265 app.port_data(port_no).Position(2)-10 app.port_data(port_no).Position(1)-190 app.port_data(port_no).Position(2)+10]...
                            ,'OutDataTypeStr',app.port_data(port_no).OutDataType);
                        convert_port = get_param(convert_handle,'PortHandles');
                        set_param(convert_port.Outport(1),'DataLogging','on','DataLoggingName',app.port_data(port_no).Name);
                        if isequal(app.port_data(port_no).BaseDataType,'Enum')
		                	old_pos = get_param(convert_handle,'Position');
		                    int_convert = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d_int8',app.harness_name,signal_no),...
		                    	'Position', [old_pos(1)-100 old_pos(2) old_pos(3)-100 old_pos(4)],'OutDataTypeStr','uint8');
		                    int_convert_port = get_param(int_convert,'PortHandles');
		                    add_line(app.harness_name,gen_handles.Outport(signal_no),int_convert_port.Inport(1));
		                    add_line(app.harness_name,int_convert_port.Outport(1),convert_port.Inport(1));
		                else
		                   	add_line(app.harness_name,gen_handles.Outport(signal_no),convert_port.Inport(1));
		                end
                        line_handle = add_line(app.harness_name,convert_port.Outport(1),ref_port_handles.Inport(port_no));
                        set_param(line_handle,'Name',app.port_data(port_no).Name);
                        port_position = app.port_data(port_no).Position;
                    elseif port_no > (app.no_rnbls + app.no_inports + 1) && port_no < (app.no_rnbls+app.no_inports+app.no_outports+2)
                        %outports
                        port_position(2) = port_position(2) + 45;
                        signal_no = signal_no + 1;
                        %data = reshape(app.port_data(port_no).Values,values(2),1);
                        %temp_timeseries = timeseries(data,re_timeStamps,'Name',app.port_data(port_no).Name);
                        signalbuilder(block,'appendsignal',timeStamps,app.port_data(port_no).Values,app.port_data(port_no).Name);
                        gen_handles = get_param(block,'PortHandles');
                        convert_handle = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d',app.harness_name,signal_no),...
                            'Position',[port_position(1)-265 port_position(2)-10 port_position(1)-190 port_position(2)+10]...
                            ,'OutDataTypeStr',app.port_data(port_no).OutDataType);
                        convert_port = get_param(convert_handle,'PortHandles');
                        set_param(convert_port.Outport(1),'DataLogging','on','DataLoggingName',app.port_data(port_no).Name);
                        if isequal(app.port_data(port_no).BaseDataType,'Enum')
		                	old_pos = get_param(convert_handle,'Position');
		                    int_convert = add_block('simulink/Signal Attributes/Data Type Conversion',sprintf('%s/convert_%d_int8',app.harness_name,signal_no),...
		                    	'Position', [old_pos(1)-100 old_pos(2) old_pos(3)-100 old_pos(4)],'OutDataTypeStr','uint8');
		                    int_convert_port = get_param(int_convert,'PortHandles');
		                    add_line(app.harness_name,gen_handles.Outport(signal_no),int_convert_port.Inport(1));
		                    add_line(app.harness_name,int_convert_port.Outport(1),convert_port.Inport(1));
		                else
		                   	add_line(app.harness_name,gen_handles.Outport(signal_no),convert_port.Inport(1));
		                end

		                outportNo = signal_no-app.no_inports;
		                set_param(ref_port_handles.Outport(outportNo),'DataLogging','on','DataLoggingName',app.port_data(port_no).Name);
		                line_handle = add_line(app.harness_name,sprintf('%s/%d',app.OnlyModelName,outportNo),sprintf('Scope/%d',outportNo),'autorouting','on');
		                set_param(line_handle,'Name',app.port_data(port_no).Name);

		                scope_port_pos = scope_ports(outportNo+app.no_outports).Position;
		                relation_handle = add_block('simulink/Logic and Bit Operations/Relational Operator',sprintf('%s/Relational_%d',app.harness_name,outportNo), 'Operator','==',...
		                	'Position', [scope_port_pos(1)-290 scope_port_pos(2)-15 scope_port_pos(1)-260 scope_port_pos(2)+15]);
		                relation_port = get_param(relation_handle,'PortHandles');
		                set_param(relation_port.Outport(1),'DataLogging','on');
		                add_line(app.harness_name,sprintf('%s/%d',app.OnlyModelName,outportNo),sprintf('Relational_%d/1',outportNo),'autorouting','on');
		                line_handle = add_line(app.harness_name,sprintf('Relational_%d/1',outportNo),sprintf('Scope/%d',outportNo+app.no_outports),'autorouting','on');
		                set_param(line_handle,'Name',sprintf('%s_Result',app.port_data(port_no).Name));

		                line_handle = add_line(app.harness_name,sprintf('convert_%d/1',signal_no),sprintf('Relational_%d/2',outportNo),'autorouting','on');
		                set_param(line_handle,'Name',sprintf('%s_Expected',app.port_data(port_no).Name));

                    end
                    %Inputs_ExpectedOutput = addElement(Inputs_ExpectedOutput,temp_timeseries);
                end
                %app.dataMatfile.Inputs_ExpectedOutput = Inputs_ExpectedOutput;
                
                app.dataMatfile.port_data = app.port_data;
                
                set_param(harness_handle,'StopTime',num2str(simulation_time));
                set_param(harness_handle, 'ZoomFactor','FitSystem');
                save_system(harness_handle);

                set_param(harness_handle,'SimulationCommand','start');
                pause(simulation_time+3)
                logged_data = evalin('base','logsout');
                app.dataMatfile.logged_data = logged_data;


                Ex_range = get(Ex_actSheet,'Range','E4');
                Ex_range = Ex_range.get('Offset',0,(app.no_inports+app.no_outports));

                time_milestone = all_time_values(1);
                time_index = 1;
                value_index = 1;
                result_status = 1; %1-> passed, 0-> failed
                for time = 0:0.01:simulation_time
                    if time < time_milestone-0.01
                      for port_no = app.no_rnbls + app.no_inports + 2: app.no_rnbls+app.no_inports+app.no_outports+1
                      	result_log = get(logged_data, sprintf('%s_Result',app.port_data(port_no).Name));
                      	result_log = result_log.Values.Data;
                      	if result_log(value_index) == 1
                      	else
                      		result_status = 0;
                      		Ex_range.Interior.ColorIndex = 3; %red
                      	end
                      	Ex_range = Ex_range.get('Offset',0,1);
                      end
                      Ex_range = Ex_range.get('Offset',0,-(app.no_outports));
                  else
                  	value_index = value_index - 1;
                  	for port_no = app.no_rnbls + app.no_inports + 2: app.no_rnbls+app.no_inports+app.no_outports+1
                      	actual_log = get(logged_data, app.port_data(port_no).Name);
                      	actual_log = actual_log.Values.Data;
                      	if isequal(app.port_data(port_no).BaseDataType,'Enum')
                      		Ex_range.Value = char(actual_log(value_index));
                      	else
                      		Ex_range.Value = actual_log(value_index);
                      	end
                      	Ex_range = Ex_range.get('Offset',0,1);
                      end
                     	if result_status == 1
                     		Ex_range.Value = 'Passed';
                     		Ex_range.Interior.ColorIndex = 4; %green
                     	else
                     		Ex_range.Value = 'Failed';
                     		Ex_range.Interior.ColorIndex = 3; %red
                     	end
                     	%disp(app.port_data(port_no).Name);
                     	Ex_range = Ex_range.get('Offset',1,-(app.no_outports));
                  	result_status = 1;
                  	time_index = time_index + 1;
                  	if time_index <= length(all_time_values)
                  		time_milestone = time_milestone + all_time_values(time_index);
                  	end
                  	value_index = value_index + 1;
                  end
                  disp(value_index);
                  value_index = value_index + 1;
                end
            end
            %disp("Done");
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed2(app, event)
            
        end

        % Value changed function: AUTOSARButton
        function AUTOSARButtonValueChanged(app, event)
            value = app.AUTOSARButton.Value;
            
        end

        % Value changed function: UpdateCases
        function UpdateCasesValueChanged(app, event)
            value = app.UpdateCases.Value;
            
        end

        % Value changed function: UpdateHarness
        function UpdateHarnessValueChanged(app, event)
            value = app.UpdateHarness.Value;
            
        end

        % Value changed function: CreateExcel
        function CreateExcelValueChanged2(app, event)
            value = app.CreateExcel.Value;
            
        end

        % Value changed function: RunTests
        function RunTestsValueChanged(app, event)
            value = app.RunTests.Value;
            
        end

        % Value changed function: MILReport
        function MILReportValueChanged(app, event)
            value = app.MILReport.Value;
            
        end

        % Value changed function: SILReport
        function SILReportValueChanged(app, event)
            value = app.SILReport.Value;
            
        end

        % Value changed function: RunSIL
        function RunSILValueChanged(app, event)
            value = app.RunSIL.Value;
            
        end

        % Value changed function: SLDVButton
        function SLDVButtonValueChanged(app, event)
            value = app.SLDVButton.Value;
            
        end

        % Button pushed function: Execute
        function ExecuteButtonPushed2(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MIL & SIL testing';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed2, true);
            app.OpenButton.Text = 'Open';
            app.CreateExcel = uicheckbox(app.UIFigure);
            app.CreateExcel.ValueChangedFcn = createCallbackFcn(app, @CreateExcelValueChanged2, true);
            app.CreateExcel.Text = ' Export TC Excel';
            app.Execute = uibutton(app.UIFigure, 'push');
            app.Execute.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed2, true);
            app.Execute.Enable = 'off';
            app.Execute.Text = 'Create Excel';
            app.SelectmodeltotestLabel = uilabel(app.UIFigure);
            app.SelectmodeltotestLabel.Text = 'Select model to test';
            app.UpdateHarness = uicheckbox(app.UIFigure);
            app.UpdateHarness.ValueChangedFcn = createCallbackFcn(app, @UpdateHarnessValueChanged, true);
            app.UpdateHarness.Text = ' Complete harness';
            app.UpdateCases = uicheckbox(app.UIFigure);
            app.UpdateCases.ValueChangedFcn = createCallbackFcn(app, @UpdateCasesValueChanged, true);
            app.UpdateCases.Text = ' Update test cases';
            app.AUTOSARButton = uibutton(app.UIFigure, 'state');
            app.AUTOSARButton.ValueChangedFcn = createCallbackFcn(app, @AUTOSARButtonValueChanged, true);
            app.AUTOSARButton.Text = 'AUTOSAR';
            app.TestingPanel = uipanel(app.UIFigure);
            app.TestingPanel.Title = '      ';
            app.RunTests = uicheckbox(app.TestingPanel);
            app.RunTests.ValueChangedFcn = createCallbackFcn(app, @RunTestsValueChanged, true);
            app.RunTests.Text = ' Run MIL';
            app.MILReport = uicheckbox(app.TestingPanel);
            app.MILReport.ValueChangedFcn = createCallbackFcn(app, @MILReportValueChanged, true);
            app.MILReport.Text = ' MIL Report';
            app.Panel = uipanel(app.TestingPanel);
            app.RunSIL = uicheckbox(app.Panel);
            app.RunSIL.ValueChangedFcn = createCallbackFcn(app, @RunSILValueChanged, true);
            app.RunSIL.Text = ' Run SIL';
            app.SLDVButton = uibutton(app.Panel, 'state');
            app.SLDVButton.ValueChangedFcn = createCallbackFcn(app, @SLDVButtonValueChanged, true);
            app.SLDVButton.Text = 'Without SLDV';
            app.SILReport = uicheckbox(app.Panel);
            app.SILReport.ValueChangedFcn = createCallbackFcn(app, @SILReportValueChanged, true);
            app.SILReport.Text = ' SIL Report';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.ModelNameEditField.Editable = 'off';
            app.ModelVersionEditFieldLabel = uilabel(app.UIFigure);
            app.ModelVersionEditFieldLabel.Text = 'Model Version:';
            app.ModelVersionEditField = uieditfield(app.UIFigure, 'text');
            app.ModelVersionEditField.Editable = 'off';
            app.ModelVersionEditField.Value = '1.0';
            app.ThisisanLabel = uilabel(app.UIFigure);
            app.ThisisanLabel.Text = 'This is an';
            app.modelLabel = uilabel(app.UIFigure);
            app.modelLabel.Text = 'model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Testing_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end