classdef arxml_process < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARXML_Process                 matlab.ui.Figure
        OpenButton                    matlab.ui.control.Button
        NameofarxmlLabel              matlab.ui.control.Label
        NameofarxmlField              matlab.ui.control.EditField
        SelectcomponentDropDownLabel  matlab.ui.control.Label
        SelectcomponentDropDown       matlab.ui.control.DropDown
        Execute                       matlab.ui.control.Button
        StatusLabel                   matlab.ui.control.Label
        UpdateModel                   matlab.ui.control.CheckBox
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ApplicationButton             matlab.ui.control.ToggleButton
        SensorActuatorButton          matlab.ui.control.ToggleButton
        CompositionsButton            matlab.ui.control.ToggleButton
        ComponentsLabel               matlab.ui.control.Label
        SelectModelButton             matlab.ui.control.Button
        NameofmodelLabel              matlab.ui.control.Label
        Nameofmodel                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        arxml_name % Description
        comp_name % Description
        arObj
        sel_cmpt
    end
    

    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.StatusEditField.Value = 'Select .arxml file';
            
            app.arxml_name = uigetfile('*.arxml','Select ARXML file');
            
            if app.arxml_name == 0
                app.StatusEditField.Value = 'Select .arxml file';
            else
                app.Execute.Enable = 1;
                app.comp_name = strsplit(app.arxml_name,'.');
                app.arObj = arxml.importer(app.arxml_name);
                
                app.StatusEditField.Value = 'ARXML imported';
                
                app.NameofarxmlField.Value = app.arxml_name;
                all_comps = getComponentNames(app.arObj,'Application');
                len = length(all_comps);
                small_comps = cell(len-2,1);
                i = 1;
                for j = 1:len
                    temp = char(all_comps(j));
                    if isequal(temp,'/Swcs/VCANOutputProcessing/VCANOutputProcessing') || isequal(temp,'/Swcs/VCANInputProcessing/VCANInputProcessing')
                        continue;
                    else
                        %split = strsplit(temp,'/');
                        %small_comps(j) = split(length(split));
                        small_comps(i) = all_comps(j);
                        i = i +1;
                    end
                end
                app.SelectcomponentDropDown.Items = small_comps;
                
                app.StatusEditField.Value = 'Select component in dropdown or check composition option';
            end
        end

        % Button pushed function: Execute
        function ExecutePushed(app, event)
            if app.CreateCompositionCheckBox.Value == 0
                app.StatusEditField.Value = 'Creating scripts with data types';
                
                createComponentAsModel(app.arObj,app.SelectcomponentDropDown.Value,'ModelPeriodicRunnablesAs','FunctionCallSubsystem','DataDictionary',sprintf('%s.sldd',app.comp_name{1,1}));
                Model_name = get_param(gcs,'Name');
                DataDictionaryObj = Simulink.data.dictionary.open(sprintf('%s.sldd',app.comp_name{1,1}));
                dDataSectObj = getSection(DataDictionaryObj,'Design Data');
                exportToFile(dDataSectObj,sprintf('%s_DerivedDatatypes.m',Model_name));
                close_system(sprintf('%s.slx',Model_name),0);
                Simulink.data.dictionary.closeAll('-discard');
                %delete *.slx;
                %delete *.slxc;
                %delete *.sldd;
                %delete(sprintf('%s.slx',Model_name));
                delete (sprintf('%s.sldd',app.comp_name{1,1}));
                
                app.StatusEditField.Value = 'Creating component frame model';
                
                createComponentAsModel(app.arObj,app.SelectcomponentDropDown.Value,'ModelPeriodicRunnablesAs','FunctionCallSubsystem');
                save_system;
                
                app.StatusEditField.Value = 'Component frame model created';
            else
                app.StatusEditField.Value = 'Creating scripts with data types';
                
                all_comsts = getComponentNames(app.arObj,'Composition');
                createCompositionAsModel(app.arObj,char(all_comsts(1)),'ModelPeriodicRunnablesAs','FunctionCallSubsystem','DataDictionary',sprintf('%s.sldd',app.comp_name{1,1}));
                Model_name = get_param(gcs,'Name');
                DataDictionaryObj = Simulink.data.dictionary.open(sprintf('%s.sldd',app.comp_name{1,1}));
                dDataSectObj = getSection(DataDictionaryObj,'Design Data');
                exportToFile(dDataSectObj,sprintf('%s_DerivedDatatypes.m',Model_name));
                
                %close_system(sprintf('%s.slx',Model_name),0);
                %Simulink.data.dictionary.closeAll('-discard');
                
                %delete *.slx;
                %delete *.slxc;
                %delete *.sldd;
                %delete(sprintf('%s.slx',Model_name));
                
                %delete (sprintf('%s.sldd',app.comp_name{1,1}));
                
                app.StatusEditField.Value = 'Creating composition frame model';
                
                %createCompositionAsModel(app.arObj,char(all_comsts(1)),'ModelPeriodicRunnablesAs','FunctionCallSubsystem');
                save_system;
                
                app.StatusEditField.Value = 'Composition frame model created';
            end
        end

        % Value changed function: UpdateModel
        function UpdateModelValueChanged(app, event)
            value = app.UpdateModel.Value;
            
        end

        % Button pushed function: SelectModelButton
        function SelectModelButtonPushed(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARXML_Process = uifigure;
            app.ARXML_Process.Name = 'ARXML to frame model';
            app.OpenButton = uibutton(app.ARXML_Process, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.NameofarxmlLabel = uilabel(app.ARXML_Process);
            app.NameofarxmlLabel.Text = 'Name of arxml:';
            app.NameofarxmlField = uieditfield(app.ARXML_Process, 'text');
            app.NameofarxmlField.Editable = 'off';
            app.SelectcomponentDropDownLabel = uilabel(app.ARXML_Process);
            app.SelectcomponentDropDownLabel.Text = 'Select component:';
            app.SelectcomponentDropDown = uidropdown(app.ARXML_Process);
            app.SelectcomponentDropDown.Items = {'Component'};
            app.SelectcomponentDropDown.Value = 'Component';
            app.Execute = uibutton(app.ARXML_Process, 'push');
            app.Execute.ButtonPushedFcn = createCallbackFcn(app, @ExecutePushed, true);
            app.Execute.Enable = 'off';
            app.Execute.Text = 'Create frame model & data type scripts';
            app.StatusLabel = uilabel(app.ARXML_Process);
            app.StatusLabel.Text = 'Select .arxml file';
            app.UpdateModel = uicheckbox(app.ARXML_Process);
            app.UpdateModel.ValueChangedFcn = createCallbackFcn(app, @UpdateModelValueChanged, true);
            app.UpdateModel.Text = ' Update Model';
            app.ButtonGroup = uibuttongroup(app.ARXML_Process);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ApplicationButton = uitogglebutton(app.ButtonGroup);
            app.ApplicationButton.Text = 'Application';
            app.ApplicationButton.Value = true;
            app.SensorActuatorButton = uitogglebutton(app.ButtonGroup);
            app.SensorActuatorButton.Text = 'Sensor-Actuator';
            app.CompositionsButton = uitogglebutton(app.ButtonGroup);
            app.CompositionsButton.Text = 'Compositions';
            app.ComponentsLabel = uilabel(app.ButtonGroup);
            app.ComponentsLabel.Text = 'Components:';
            app.SelectModelButton = uibutton(app.ARXML_Process, 'push');
            app.SelectModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectModelButtonPushed, true);
            app.SelectModelButton.Enable = 'off';
            app.SelectModelButton.Text = 'Select Model';
            app.NameofmodelLabel = uilabel(app.ARXML_Process);
            app.NameofmodelLabel.Enable = 'off';
            app.NameofmodelLabel.Text = 'Name of model:';
            app.Nameofmodel = uieditfield(app.ARXML_Process, 'text');
            app.Nameofmodel.Editable = 'off';
            app.Nameofmodel.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = arxml_process

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARXML_Process)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARXML_Process)
        end
    end
end